classdef final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConnectionLamp                  matlab.ui.control.Lamp
        ConnectionLampLabel             matlab.ui.control.Label
        REALTIMEDISTANCEDETECTIONLabel  matlab.ui.control.Label
        StopButton                      matlab.ui.control.StateButton
        Panel1CartesianFigure           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        Panel2PolarFigure               matlab.ui.container.Panel
        RadarOnOffButton                matlab.ui.control.StateButton
        Slider                          matlab.ui.control.Slider
        ModesButtonGroup                matlab.ui.container.ButtonGroup
        DistanceButton                  matlab.ui.control.RadioButton
        RadarButton                     matlab.ui.control.RadioButton
        ClearButton                     matlab.ui.control.RadioButton
        AngleButton                     matlab.ui.control.RadioButton
        DeviationButton                 matlab.ui.control.RadioButton
        SpeedButton                     matlab.ui.control.RadioButton
        DisconnectButton                matlab.ui.control.Button
        SetupButton                     matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
    end

    properties (Access = public)
        Property % Description
        TS = 200; QUANT = 512; ENCOD = 9; ENCOD2 = 5;
        bt; pax; running;
    end        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.pax = polaraxes(app.Panel2PolarFigure);
            app.pax.RTick = (1:1:100-1);
            app.pax.RTickLabel = compose('10^{%2d}',(1:1:100-1));
            thetalim(app.pax, [0 180]);
            
            app.Panel2PolarFigure.Visible = 'off';
            app.Panel1CartesianFigure.Visible = 'on';
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            %% start connection
         
            app.bt = bluetooth('HC-06', 1);
            app.ConnectionLamp.Color = "Green";
        end

        % Value changed function: RadarOnOffButton
        function RadarOnOffButtonValueChanged(app, event)
            value = app.RadarOnOffButton.Value;
            if value
                write(app.bt, '1');
            else
                write(app.bt, '0');
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            delete(app.bt)
            app.ConnectionLamp.Color = "Red";
            app.running = 0;
        end

        % Selection changed function: ModesButtonGroup
        function ModesButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModesButtonGroup.SelectedObject;
            if(selectedButton == app.RadarButton)
                app.Panel2PolarFigure.Visible = 'on';
                app.Panel1CartesianFigure.Visible = 'off';
            else
                app.Panel2PolarFigure.Visible = 'off';
                app.Panel1CartesianFigure.Visible = 'on';
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.UIAxes);

            flush(app.bt);
            time = 1000;
            tempar = ones(1,5);
            anglear = ones(1,3);
            dataplot = animatedline(app.UIAxes);
            dist = zeros(1,180/6+1);
            angles = deg2rad(0:6:180);
            app.running = 1;
            while(app.running)
                clearpoints(dataplot);
                for i = 1:time
                    if app.running == 0
                        break
                    end
                    if(app.StopButton.Value == 1)
                        out = read(app.bt, app.ENCOD+app.ENCOD2, "string");
                        continue;
                    end
                    
                    out = read(app.bt, app.ENCOD+app.ENCOD2, "string");
                    data = str2double(out);
                    
                    decoded2 = 0;
                    for j = 0:(app.ENCOD2-1)
                        decoded2 = decoded2 + mod(data,2)*2^j;
                        data = fix(data/10);
                    end

                    decoded = 0;
                    for j = 0:(app.ENCOD-1)
                        decoded = decoded + mod(data,2)*2^j;
                        data = fix(data/10);
                    end

                    if (decoded == 511)
                        decoded = temp;
                    end
                    
                    tempar(1) = tempar(2);
                    tempar(2) = tempar(3);
                    tempar(3) = tempar(4);
                    tempar(4) = tempar(5);
                    tempar(5) = decoded;
                    filtered = medfilt1(tempar, 5);

                    anglear(1) = anglear(2);
                    anglear(2) = anglear(3);
                    anglear(3) = decoded2;
                    

                    filt_decod =  filtered(3);
%                   [~,ydata] = getpoints(dataplot);
%                   errorfree = smoothdata(ydata,'movmedian',3);    
                    
                    if app.DistanceButton.Value
                        axis(app.UIAxes, [(i-app.Slider.Value)*app.TS/1000 i*app.TS/1000 0 inf]);
                        addpoints(dataplot, i*app.TS/1000, filt_decod);
                        ylabel(app.UIAxes,'Distance (cm)'); xlabel(app.UIAxes,'Time (s)'); title(app.UIAxes,'Distance');
                    elseif app.SpeedButton.Value
                        axis(app.UIAxes, [(i-app.Slider.Value)*app.TS/1000 i*app.TS/1000 -inf inf]);
                        zoom(app.UIAxes,'yon');
                        speed = (filt_decod - temp)*10/app.TS;
                        addpoints(dataplot, i*app.TS/1000, speed);
                        ylabel(app.UIAxes,'Speed m/s'); xlabel(app.UIAxes,'Time (s)'); title(app.UIAxes,'Speed');
                    elseif app.DeviationButton.Value
                        axis(app.UIAxes, [(i-app.Slider.Value)*app.TS/1000 i*app.TS/1000 -60 60]);
                        deviation = (filt_decod - temp);
                        addpoints(dataplot, i*app.TS/1000 , deviation);
                        ylabel(app.UIAxes,'Deviation'); xlabel(app.UIAxes,'Time (s)'); title(app.UIAxes,'Deviation');
                    elseif app.AngleButton.Value
                        axis(app.UIAxes, [(i-app.Slider.Value)*app.TS/1000 i*app.TS/1000 0 180]);
                        addpoints(dataplot, i*app.TS/1000, decoded2*6);
                        ylabel(app.UIAxes,'Speed m/s'); xlabel(app.UIAxes,'Time (s)'); title(app.UIAxes,'Speed');
                    elseif app.ClearButton.Value
                        axis(app.UIAxes, [(i-app.Slider.Value)*app.TS/1000 i*app.TS/1000 -inf inf]);
                        clearpoints(dataplot);
                        flush(app.bt);
                    elseif app.RadarButton.Value
%                         dist(anglear(1)+1) = log10(filt_decod+1);
                        dist(anglear(1)+1) = filt_decod;
                        polarplot(app.pax, angles, dist);
%                         app.pax.RTick = (1:1:100-1);
%                         app.pax.RTickLabel = compose('10^{%2d}',(1:1:100-1));
                        thetalim(app.pax,[0 180]);
                    end
                    
                    temp = filt_decod;
                    drawnow
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ModesButtonGroup = uibuttongroup(app.UIFigure);
            app.ModesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModesButtonGroupSelectionChanged, true);
            app.ModesButtonGroup.Title = 'Modes';
            app.SpeedButton = uiradiobutton(app.ModesButtonGroup);
            app.SpeedButton.Text = 'Speed';
            app.DeviationButton = uiradiobutton(app.ModesButtonGroup);
            app.DeviationButton.Text = 'Deviation';
            app.AngleButton = uiradiobutton(app.ModesButtonGroup);
            app.AngleButton.Text = 'Angle';
            app.ClearButton = uiradiobutton(app.ModesButtonGroup);
            app.ClearButton.Text = 'Clear';
            app.ClearButton.Value = true;
            app.RadarButton = uiradiobutton(app.ModesButtonGroup);
            app.RadarButton.Text = 'Radar';
            app.DistanceButton = uiradiobutton(app.ModesButtonGroup);
            app.DistanceButton.Text = 'Distance';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [5 55];
            app.Slider.Value = 10;
            app.RadarOnOffButton = uibutton(app.UIFigure, 'state');
            app.RadarOnOffButton.ValueChangedFcn = createCallbackFcn(app, @RadarOnOffButtonValueChanged, true);
            app.RadarOnOffButton.Text = 'Radar On/Off';
            app.Panel2PolarFigure = uipanel(app.UIFigure);
            app.Panel2PolarFigure.Title = 'Panel 2 - Polar Figure';
            app.Panel1CartesianFigure = uipanel(app.UIFigure);
            app.Panel1CartesianFigure.Title = 'Panel 1 - Cartesian Figure';
            app.UIAxes = uiaxes(app.Panel1CartesianFigure);
            title(app.UIAxes, 'Real Time Data Plot')
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Text = 'Stop';
            app.REALTIMEDISTANCEDETECTIONLabel = uilabel(app.UIFigure);
            app.REALTIMEDISTANCEDETECTIONLabel.Text = 'REAL TIME DISTANCE DETECTION';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end